classdef aplikacija < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Mehatronika                 matlab.ui.Figure
        UpravljanjemotorimaPanel    matlab.ui.container.Panel
        Label                       matlab.ui.control.Label
        Label_2                     matlab.ui.control.Label
        Label_3                     matlab.ui.control.Label
        Label_4                     matlab.ui.control.Label
        RameSlider_2Label           matlab.ui.control.Label
        RameSlider_2                matlab.ui.control.Slider
        LakatSliderLabel            matlab.ui.control.Label
        LakatSlider                 matlab.ui.control.Slider
        BazaSliderLabel             matlab.ui.control.Label
        BazaSlider                  matlab.ui.control.Slider
        KljetaSliderLabel           matlab.ui.control.Label
        KljetaSlider                matlab.ui.control.Slider
        TabGroup2                   matlab.ui.container.TabGroup
        KontroleTab                 matlab.ui.container.Tab
        SpremipozicijuButton        matlab.ui.control.Button
        PokrenipokreteButton        matlab.ui.control.Button
        ResetirajButton             matlab.ui.control.Button
        LEDONButton                 matlab.ui.control.Button
        LEDOFFButton                matlab.ui.control.Button
        PostavkeTab                 matlab.ui.container.Tab
        ArduinoPORTDropDownLabel    matlab.ui.control.Label
        ArduinoPORTDropDown         matlab.ui.control.DropDown
        OKButton                    matlab.ui.control.Button
        OdabraniPORTEditFieldLabel  matlab.ui.control.Label
        OdabraniPORTEditField       matlab.ui.control.EditField
        TextArea                    matlab.ui.control.TextArea
        CheckBox                    matlab.ui.control.CheckBox
        OTVORIButton                matlab.ui.control.Button
        ZATVORIButton               matlab.ui.control.Button
        TabGroup3                   matlab.ui.container.TabGroup
        UputeTab                    matlab.ui.container.Tab
        TextArea_2                  matlab.ui.control.TextArea
        OaplikacijiTab              matlab.ui.container.Tab
        TextArea_3                  matlab.ui.control.TextArea
        TabGroup4                   matlab.ui.container.TabGroup
        ModelTab                    matlab.ui.container.Tab
        Image                       matlab.ui.control.Image
        ShemaTab                    matlab.ui.container.Tab
        Image2                      matlab.ui.control.Image
        TextArea_4                  matlab.ui.control.TextArea
    end

    
    properties (Access = public)
        a; % Arduino varijabla
        sR; % Servo za rame varijabla
        sL; % Servo za lakat varijabla
        sB; % Servo za bazu varijabla
        sK; % Servo za klješta varijabla
        current_pos_sB;
        current_pos_sL;
        current_pos_sR;
        current_pos_sK;
        kut;
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
        app.a = arduino();
        app.sR = servo(app.a, 'D3'); % Rame na pinu 3
        app.sL = servo(app.a, 'D6'); % Lakat na pinu 6
        app.sB = servo(app.a, 'D9'); % Baza na pinu 9
        app.sK = servo(app.a, 'D11'); % Klješta na pinu 11
        app.TextArea.Value = seriallist;
        set(app.ArduinoPORTDropDown, 'Items', seriallist);
        set(app.ArduinoPORTDropDown, 'Value', app.ArduinoPORTDropDown.Items(1));
        set(app.ArduinoPORTDropDown, 'ItemsData', seriallist);
        app.OdabraniPORTEditField.Value = app.ArduinoPORTDropDown.Value;
        end

        % Value changed function: ArduinoPORTDropDown
        function ArduinoPORTDropDownValueChanged(app, event)
            %value = app.ArduinoPORTDropDown.Value;
        end

        % Value changing function: RameSlider_2
        function RameSlider_2ValueChanging(app, event)
            changingValue = event.Value;
            app.Label.Text = num2str(changingValue,4);
            writePosition(app.sR, changingValue);
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            app.OdabraniPORTEditField.Value = app.ArduinoPORTDropDown.Value;
        end

        % Button pushed function: LEDONButton
        function LEDONButtonPushed(app, event)
            writeDigitalPin(app.a, 'D2', 1);
        end

        % Button pushed function: LEDOFFButton
        function LEDOFFButtonPushed(app, event)
            writeDigitalPin(app.a, 'D2', 0);
        end

        % Value changing function: BazaSlider
        function BazaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_3.Text = num2str(changingValue,4);
            writePosition(app.sB, changingValue);
        end

        % Value changing function: KljetaSlider
        function KljetaSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_4.Text = num2str(changingValue,4);
            writePosition(app.sK, changingValue);
        end

        % Value changing function: LakatSlider
        function LakatSliderValueChanging(app, event)
            changingValue = event.Value;
            app.Label_2.Text = num2str(changingValue,4);
            writePosition(app.sL, changingValue);
        end

        % Close request function: Mehatronika
        function MehatronikaCloseRequest(app, event)
            clear a sR sL sB sK;
            delete(app)         
        end

        % Value changed function: CheckBox
        function CheckBoxValueChanged(app, event)
            value = app.CheckBox.Value;
            if value == 0
                app.KljetaSlider.Enable = 'off';
            end
            if value == 1
                app.KljetaSlider.Enable = 'on';
            end
        end

        % Button pushed function: OTVORIButton
        function OTVORIButtonPushed(app, event)
            writePosition(app.sK, 0.4);
        end

        % Button pushed function: ZATVORIButton
        function ZATVORIButtonPushed(app, event)
            writePosition(app.sK, 0.6);
        end

        % Button pushed function: ResetirajButton
        function ResetirajButtonPushed(app, event)
        app.current_pos_sB = readPosition(app.sB);
        app.current_pos_sL = readPosition(app.sL);
        app.current_pos_sR = readPosition(app.sR);
        app.current_pos_sK = readPosition(app.sK);
            for x = app.current_pos_sB:0.025:0.5
                writePosition(app.sB, x);
                pause(0.02);
            end
            pause(0.5);
            for x = app.current_pos_sL:0.025:0.9
                writePosition(app.sL, x);
                pause(0.02);
            end
            pause(0.5);
            for x = app.current_pos_sR:0.025:0.5
                writePosition(app.sR, x);
                pause(0.02);
            end
            pause(0.5);
            for x = app.current_pos_sK:0.025:0.5
                writePosition(app.sK, x);
                pause(0.02);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Mehatronika = uifigure('Visible', 'off');
            app.Mehatronika.Name = 'Mehatronika Seminarski Rad';
            app.Mehatronika.CloseRequestFcn = createCallbackFcn(app, @MehatronikaCloseRequest, true);
            app.UpravljanjemotorimaPanel = uipanel(app.Mehatronika);
            app.UpravljanjemotorimaPanel.Title = 'Upravljanje motorima';
            app.Label = uilabel(app.UpravljanjemotorimaPanel);
            app.Label.Text = '0';
            app.Label_2 = uilabel(app.UpravljanjemotorimaPanel);
            app.Label_2.Text = '0';
            app.Label_3 = uilabel(app.UpravljanjemotorimaPanel);
            app.Label_3.Text = '0';
            app.Label_4 = uilabel(app.UpravljanjemotorimaPanel);
            app.Label_4.Text = '0';
            app.RameSlider_2Label = uilabel(app.UpravljanjemotorimaPanel);
            app.RameSlider_2Label.Text = 'Rame';
            app.RameSlider_2 = uislider(app.UpravljanjemotorimaPanel);
            app.RameSlider_2.Limits = [0 1];
            app.RameSlider_2.ValueChangingFcn = createCallbackFcn(app, @RameSlider_2ValueChanging, true);
            app.RameSlider_2.Value = 0.5;
            app.LakatSliderLabel = uilabel(app.UpravljanjemotorimaPanel);
            app.LakatSliderLabel.Text = 'Lakat';
            app.LakatSlider = uislider(app.UpravljanjemotorimaPanel);
            app.LakatSlider.Limits = [0.4 1];
            app.LakatSlider.ValueChangingFcn = createCallbackFcn(app, @LakatSliderValueChanging, true);
            app.LakatSlider.Value = 0.9;
            app.BazaSliderLabel = uilabel(app.UpravljanjemotorimaPanel);
            app.BazaSliderLabel.Text = 'Baza';
            app.BazaSlider = uislider(app.UpravljanjemotorimaPanel);
            app.BazaSlider.Limits = [0 1];
            app.BazaSlider.ValueChangingFcn = createCallbackFcn(app, @BazaSliderValueChanging, true);
            app.BazaSlider.Value = 0.5;
            app.KljetaSliderLabel = uilabel(app.UpravljanjemotorimaPanel);
            app.KljetaSliderLabel.Enable = 'off';
            app.KljetaSliderLabel.Text = 'Klješta';
            app.KljetaSlider = uislider(app.UpravljanjemotorimaPanel);
            app.KljetaSlider.Limits = [0 1];
            app.KljetaSlider.ValueChangingFcn = createCallbackFcn(app, @KljetaSliderValueChanging, true);
            app.KljetaSlider.Enable = 'off';
            app.KljetaSlider.Value = 0.5;
            app.TabGroup2 = uitabgroup(app.UpravljanjemotorimaPanel);
            app.KontroleTab = uitab(app.TabGroup2);
            app.KontroleTab.Title = 'Kontrole';
            app.SpremipozicijuButton = uibutton(app.KontroleTab, 'push');
            app.SpremipozicijuButton.Enable = 'off';
            app.SpremipozicijuButton.Text = 'Spremi poziciju';
            app.PokrenipokreteButton = uibutton(app.KontroleTab, 'push');
            app.PokrenipokreteButton.Enable = 'off';
            app.PokrenipokreteButton.Text = 'Pokreni pokrete';
            app.ResetirajButton = uibutton(app.KontroleTab, 'push');
            app.ResetirajButton.ButtonPushedFcn = createCallbackFcn(app, @ResetirajButtonPushed, true);
            app.ResetirajButton.Enable = 'off';
            app.ResetirajButton.Text = 'Resetiraj';
            app.LEDONButton = uibutton(app.KontroleTab, 'push');
            app.LEDONButton.ButtonPushedFcn = createCallbackFcn(app, @LEDONButtonPushed, true);
            app.LEDONButton.Text = 'LED ON';
            app.LEDOFFButton = uibutton(app.KontroleTab, 'push');
            app.LEDOFFButton.ButtonPushedFcn = createCallbackFcn(app, @LEDOFFButtonPushed, true);
            app.LEDOFFButton.Text = 'LED OFF';
            app.PostavkeTab = uitab(app.TabGroup2);
            app.PostavkeTab.Title = 'Postavke';
            app.ArduinoPORTDropDownLabel = uilabel(app.PostavkeTab);
            app.ArduinoPORTDropDownLabel.Text = 'Arduino PORT';
            app.ArduinoPORTDropDown = uidropdown(app.PostavkeTab);
            app.ArduinoPORTDropDown.Items = {};
            app.ArduinoPORTDropDown.ValueChangedFcn = createCallbackFcn(app, @ArduinoPORTDropDownValueChanged, true);
            app.ArduinoPORTDropDown.Value = {};
            app.OKButton = uibutton(app.PostavkeTab, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.OdabraniPORTEditFieldLabel = uilabel(app.PostavkeTab);
            app.OdabraniPORTEditFieldLabel.Text = 'Odabrani PORT';
            app.OdabraniPORTEditField = uieditfield(app.PostavkeTab, 'text');
            app.OdabraniPORTEditField.Editable = 'off';
            app.OdabraniPORTEditField.Value = 'Vrijednost';
            app.TextArea = uitextarea(app.PostavkeTab);
            app.TextArea.Editable = 'off';
            app.TextArea.Tooltip = {'Popis COM portova na računalu'};
            app.TextArea.Value = {'Portovi'};
            app.CheckBox = uicheckbox(app.UpravljanjemotorimaPanel);
            app.CheckBox.ValueChangedFcn = createCallbackFcn(app, @CheckBoxValueChanged, true);
            app.CheckBox.Text = '';
            app.OTVORIButton = uibutton(app.UpravljanjemotorimaPanel, 'push');
            app.OTVORIButton.ButtonPushedFcn = createCallbackFcn(app, @OTVORIButtonPushed, true);
            app.OTVORIButton.Text = 'OTVORI';
            app.ZATVORIButton = uibutton(app.UpravljanjemotorimaPanel, 'push');
            app.ZATVORIButton.ButtonPushedFcn = createCallbackFcn(app, @ZATVORIButtonPushed, true);
            app.ZATVORIButton.Text = 'ZATVORI';
            app.TabGroup3 = uitabgroup(app.Mehatronika);
            app.UputeTab = uitab(app.TabGroup3);
            app.UputeTab.Title = 'Upute';
            app.TextArea_2 = uitextarea(app.UputeTab);
            app.TextArea_2.Value = {'1. Odspojite sve ostale Arduino uređaje'; '2. Pogledajte shemu spajanja'; '3. Aplikacija će pri pokretanju uspostaviti vezu s Arduinom'; '4 Koristite klizaće na desnoj strani za upravljanje motorima kako je to naznačeno na slici'; '5. Klizače pomjerati polako, jer Arduino direktno upisuje vrijednosti na motore'; '6. Vrijednosti su 0 - 1 i gdje je 0 - 0 stupnjeva a 1 - 180 stupnjeva'; ''; 'NAPOMENA: "Postavke" i "Kontrole" su u eksperimentalnoj fazi. Koristi samo kontrole za paljenje i gašenje LED'};
            app.OaplikacijiTab = uitab(app.TabGroup3);
            app.OaplikacijiTab.Title = 'O aplikaciji';
            app.TextArea_3 = uitextarea(app.OaplikacijiTab);
            app.TextArea_3.Value = {'Autor: Stjepan Mamuša'; 'Službeni mail: sm45296@oss.unist.hr'; 'Privatni mail: stjepanmamusa@outlook.com'; 'Studentska stranica: student.oss.unist.hr/~sm45296'; 'Github stranica: smamusa.github.io'; 'Osobna stranica: stjepanmamusa.com'; ''; 'Mobilni kontakt: 097 648 7032'};
            app.TabGroup4 = uitabgroup(app.Mehatronika);
            app.ModelTab = uitab(app.TabGroup4);
            app.ModelTab.Title = 'Model';
            app.Image = uiimage(app.ModelTab);
            app.Image.ImageSource = 'main-arm.png';
            app.ShemaTab = uitab(app.TabGroup4);
            app.ShemaTab.Title = 'Shema';
            app.Image2 = uiimage(app.ShemaTab);
            app.Image2.ImageSource = 'Shema.PNG';
            app.TextArea_4 = uitextarea(app.ShemaTab);
            app.TextArea_4.Value = {'Klješta motor na PWM pin 11'; 'Baza motor na PWM pin 9'; 'Lakat motor na PWM pin 6'; 'Rame motor na PWM pin 3'; 'Napajanje 5V, minimalne struje 2A'; 'Ledicu spojiti na pin 2'};
            % Show the figure after all components are created
            app.Mehatronika.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aplikacija

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Mehatronika)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Mehatronika)
        end
    end
end